classdef IF4073_Tugas1_13519029_13519135 < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        debugLabel                matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        processedImageLabel       matlab.ui.control.Label
        processedImg              matlab.ui.control.Image
        specImageLabel            matlab.ui.control.Label
        specImg                   matlab.ui.control.Image
        baseImageLabel            matlab.ui.control.Label
        baseImg                   matlab.ui.control.Image
        processButton             matlab.ui.control.Button
        pickSpecificationButton   matlab.ui.control.Button
        pickBaseButton            matlab.ui.control.Button
        aValLabel                 matlab.ui.control.Label
        bValLabel                 matlab.ui.control.Label
        gammaValLabel             matlab.ui.control.Label
        cValLabel                 matlab.ui.control.Label
        creatorLabel              matlab.ui.control.Label
        valueSlider               matlab.ui.control.Slider
        valueSliderLabel          matlab.ui.control.Label
        cvalueSlider              matlab.ui.control.Slider
        cvalueSliderLabel         matlab.ui.control.Label
        bvalueSlider              matlab.ui.control.Slider
        bvalueLabel               matlab.ui.control.Label
        avalueSlider              matlab.ui.control.Slider
        avaluesliderLabel         matlab.ui.control.Label
        functionsDropDown         matlab.ui.control.DropDown
        FunctionsDropDownLabel    matlab.ui.control.Label
        processedBlueUIAxes       matlab.ui.control.UIAxes
        processedGreenUIAxes      matlab.ui.control.UIAxes
        processedRedUIAxes        matlab.ui.control.UIAxes
        processedGrayscaleUIAxes  matlab.ui.control.UIAxes
        specBlueUIAxes            matlab.ui.control.UIAxes
        specGreenUIAxes           matlab.ui.control.UIAxes
        specRedUIAxes             matlab.ui.control.UIAxes
        specGrayscaleUIAxes       matlab.ui.control.UIAxes
        originalBlueUIAxes        matlab.ui.control.UIAxes
        originalGreenUIAxes       matlab.ui.control.UIAxes
        originalRedUIAxes         matlab.ui.control.UIAxes
        originalGrayscaleUIAxes   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        aVal double = 1; % 'a' Value
        bVal double = 1; % 'b' value
        baseImageVar uint8 % variable containing matrix of base image
        cVal double = 1; % 'c' value
        gammaVal double = 1; % 'γ' value
        processedImageVar uint8 % variable containing matrix of processed image
        specImageVar uint8 % variable containing specification image matrix
    end
    
    
    methods (Access = private)
        %{
          Description: implementation of rgb values to grayscale values
          Output: 2D matrix of size row(matrix), col(matrix), with grayscale
            values using the formula 0.2989 * Red + 0.5870 * Green + 0.1140 * Blue
          Input: 2D Matrix of n dimension n > 0
        %}
        function grayscale = rgb_to_grayscale(comp, matrix)
            [~, ~, dim] = size(matrix);
            % if image is already in grayscale values
            if dim == 1, grayscale = matrix; return, end
        
            grayscale = matrix(:,:,1) * 0.2989 + matrix(:,:,2) * 0.587 + matrix(:,:,3) * 0.114;
            grayscale = uint8(grayscale);
        end

        %{
          Description: Calculates occurences of each pixel value for a given matrix
          Output: Vector with 256 columns with values [0,255] of occurences
          Input: 2D pixel matrix
        %}
        function occ = calc_occurences(comp, matrix)
            occ = zeros(1,256);
            [row, col] = size(matrix);
            for r = 1:row
                for c = 1:col
                occ(matrix(r,c)+1) = occ(matrix(r,c)+1) + 1;
                end
            end
        end
        
        %{
          Description: Plots occurences of pixel using stem function
          Output: Stem of pixel occurences
          Input: Vector with 256 columns of pixel occurences,
            target plot destination
        %}
        function occurences_to_plot(comp, occ, target)
            target.Visible = "on";
            occ(occ==0) = nan; % converts 0 to nan, to not show in stem plot
            stem(target, 0:255, occ, 'MarkerSize', 1);
            xlim(target, [0 255]); % limit x value to in range of [0, 255]
        end

        %{
          Description: Plots stem of image rgb and/or grayscale values, with option to show images
          Output: Figures of plots and images
          Input: image matrix (grayscale/colored)
        %}
        function img_plot(comp, img, targets)
            [~, ~, dim] = size(img);
            % if image is already in grayscale
            if dim == 1, comp.occurences_to_plot(comp.calc_occurences(img), targets(4)); return, end
        
            r = img(:,:,1);
            g = img(:,:,2);
            b = img(:,:,3);
            gr = comp.rgb_to_grayscale(img);
            comp.occurences_to_plot(comp.calc_occurences(r), targets(1));
            comp.occurences_to_plot(comp.calc_occurences(g), targets(2));
            comp.occurences_to_plot(comp.calc_occurences(b), targets(3));
            comp.occurences_to_plot(comp.calc_occurences(gr), targets(4));
        end
        

        %{
          Description: Fix image quality using image brightening using formula s = c * log(1 + r)
          Output: Transformed 2D matrix using the image brightening formula
          Input: 2D Matrix with x dimension; x in range (0, 3], a, b
        %}
        function mat = brightening(comp, matrix, a, b)
            [~, ~, dim] = size(matrix);
            mat = matrix;
            for d = 1:dim
                mat(:,:,d) = uint8((matrix(:,:,d) * a) + b);
            end
        end
        
        function results = baseTargets(comp)
            results = [comp.originalRedUIAxes, comp.originalGreenUIAxes, comp.originalBlueUIAxes, comp.originalGrayscaleUIAxes];
        end

        function results = processedTargets(comp)
            results = [comp.processedRedUIAxes, comp.processedGreenUIAxes, comp.processedBlueUIAxes, comp.processedGrayscaleUIAxes];
        end

        function results = specTargets(comp)
            results = [comp.specRedUIAxes, comp.specGreenUIAxes, comp.specBlueUIAxes, comp.specGrayscaleUIAxes];
        end

        function showProcessedImage(comp)
            [~, ~, dim] = size(comp.processedImageVar);
            comp.processedImg.Visible = "on";
            if dim == 1, comp.processedImg.ImageSource = repmat(comp.processedImageVar, 1, 1, 3); else, comp.processedImg.ImageSource = comp.processedImageVar; end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: avalueSlider
        function avalueSliderValueChanged(comp, event)
            value = comp.avalueSlider.Value;
            comp.aValLabel.Text = string(value);
            comp.aVal = value;
        end

        % Value changed function: bvalueSlider
        function bvalueSliderValueChanged(comp, event)
            value = comp.bvalueSlider.Value;
            comp.bValLabel.Text = string(value);
            comp.bVal = value;
        end

        % Value changed function: cvalueSlider
        function cvalueSliderValueChanged(comp, event)
            value = comp.cvalueSlider.Value;
            comp.cValLabel.Text = string(value);
            comp.cVal = value;
        end

        % Value changed function: valueSlider
        function valueSliderValueChanged(comp, event)
            value = comp.valueSlider.Value;
            comp.gammaValLabel.Text = string(value);
            comp.gammaVal = value;
        end

        % Button pushed function: pickBaseButton
        function pickBaseButtonPushed(comp, event)
            [base_file_name, base_file_path] = uigetfile({'*.tif;*.tiff;*.png;*.jpeg;*.jpg'}, 'Image Selector');
            if ~base_file_name, return, end
            comp.baseImageVar = imread(strcat(base_file_path, base_file_name));
            [~, ~, dim] = size(comp.baseImageVar);
            comp.baseImg.Visible = "on";
            if dim == 1, comp.baseImg.ImageSource = repmat(comp.baseImageVar, 1, 1, 3); else, comp.baseImg.ImageSource = comp.baseImageVar; end
            comp.img_plot(comp.baseImageVar, comp.baseTargets());
        end

        % Button pushed function: pickSpecificationButton
        function pickSpecificationButtonPushed(comp, event)
            [spec_file_name, spec_file_path] = uigetfile({'*.tif;*.tiff;*.png;*.jpeg;*.jpg'}, 'Image Selector');
            if ~spec_file_name, return, end
            comp.specImageVar = imread(strcat(spec_file_path, spec_file_name));
            [~, ~, dim] = size(comp.specImageVar);
            comp.specImg.Visible = "on";
            if dim == 1, comp.specImg.ImageSource = repmat(comp.specImageVar, 1, 1, 3); else, comp.specImg.ImageSource = comp.specImageVar; end
            comp.img_plot(comp.specImageVar, comp.specTargets());
        end

        % Button pushed function: processButton
        function processButtonPushed(comp, event)
            if ~comp.baseImageVar; return, end % return if base image is not loaded
            switch comp.functionsDropDown.Value
                case "Show Histogram"
                    comp.debugLabel.Text = "Show Histogram";
                    return
                case "Image Brightening"
                    comp.debugLabel.Text = "Image Brightening";
                    comp.processedImageVar = comp.brightening(comp.baseImageVar, comp.aVal, comp.bVal);
                    comp.img_plot(comp.processedImageVar, comp.processedTargets());
                    comp.showProcessedImage();
                    return
                case "Log Transformation"
                    comp.debugLabel.Text = "Log Transformation";
                    return
                case "Power Transformation"
                    comp.debugLabel.Text = "Power Transformation";
                    return
                case "Contrast Stretching"
                    comp.debugLabel.Text = "Contrast Stretching";
                    return
                case "Histogram Equalization"
                    comp.debugLabel.Text = "Histogram Equalization";
                    return
                case "Histogram Specification"
                    comp.debugLabel.Text = "Histogram Specification";
                    return
                otherwise
                    comp.debugLabel.Text = "Otherwise";
            end
            %{
                Show Histogram
                Image Brightening
                Log Transformation
                Power Transformation
                Contrast Stretching
                Histogram Equalization
                Histogram Specification
            %}
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 1930 1091];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.originalGrayscaleUIAxes = uiaxes(comp);
            title(comp.originalGrayscaleUIAxes, 'Grayscale Stem Chart [Original]')
            xlabel(comp.originalGrayscaleUIAxes, 'X')
            ylabel(comp.originalGrayscaleUIAxes, 'Y')
            zlabel(comp.originalGrayscaleUIAxes, 'Z')
            comp.originalGrayscaleUIAxes.Visible = 'off';
            comp.originalRedUIAxes = uiaxes(comp);
            title(comp.originalRedUIAxes, 'Red Stem Chart [Original]')
            xlabel(comp.originalRedUIAxes, 'X')
            ylabel(comp.originalRedUIAxes, 'Y')
            zlabel(comp.originalRedUIAxes, 'Z')
            comp.originalRedUIAxes.Visible = 'off';
            comp.originalGreenUIAxes = uiaxes(comp);
            title(comp.originalGreenUIAxes, 'Green Stem Chart [Original]')
            xlabel(comp.originalGreenUIAxes, 'X')
            ylabel(comp.originalGreenUIAxes, 'Y')
            zlabel(comp.originalGreenUIAxes, 'Z')
            comp.originalGreenUIAxes.Visible = 'off';
            comp.originalBlueUIAxes = uiaxes(comp);
            title(comp.originalBlueUIAxes, 'Blue Stem Chart [Original]')
            xlabel(comp.originalBlueUIAxes, 'X')
            ylabel(comp.originalBlueUIAxes, 'Y')
            zlabel(comp.originalBlueUIAxes, 'Z')
            comp.originalBlueUIAxes.Visible = 'off';
            comp.specGrayscaleUIAxes = uiaxes(comp);
            title(comp.specGrayscaleUIAxes, 'Grayscale Stem Chart [Specification]')
            xlabel(comp.specGrayscaleUIAxes, 'X')
            ylabel(comp.specGrayscaleUIAxes, 'Y')
            zlabel(comp.specGrayscaleUIAxes, 'Z')
            comp.specGrayscaleUIAxes.Visible = 'off';
            comp.specRedUIAxes = uiaxes(comp);
            title(comp.specRedUIAxes, 'Red Stem Chart [Specification]')
            xlabel(comp.specRedUIAxes, 'X')
            ylabel(comp.specRedUIAxes, 'Y')
            zlabel(comp.specRedUIAxes, 'Z')
            comp.specRedUIAxes.Visible = 'off';
            comp.specGreenUIAxes = uiaxes(comp);
            title(comp.specGreenUIAxes, 'Green Stem Chart [Specification]')
            xlabel(comp.specGreenUIAxes, 'X')
            ylabel(comp.specGreenUIAxes, 'Y')
            zlabel(comp.specGreenUIAxes, 'Z')
            comp.specGreenUIAxes.Visible = 'off';
            comp.specBlueUIAxes = uiaxes(comp);
            title(comp.specBlueUIAxes, 'Blue Stem Chart [Specification]')
            xlabel(comp.specBlueUIAxes, 'X')
            ylabel(comp.specBlueUIAxes, 'Y')
            zlabel(comp.specBlueUIAxes, 'Z')
            comp.specBlueUIAxes.Visible = 'off';
            comp.processedGrayscaleUIAxes = uiaxes(comp);
            title(comp.processedGrayscaleUIAxes, 'Grayscale Stem Chart [Processed]')
            xlabel(comp.processedGrayscaleUIAxes, 'X')
            ylabel(comp.processedGrayscaleUIAxes, 'Y')
            zlabel(comp.processedGrayscaleUIAxes, 'Z')
            comp.processedGrayscaleUIAxes.Visible = 'off';
            comp.processedRedUIAxes = uiaxes(comp);
            title(comp.processedRedUIAxes, 'Red Stem Chart [Processed]')
            xlabel(comp.processedRedUIAxes, 'X')
            ylabel(comp.processedRedUIAxes, 'Y')
            zlabel(comp.processedRedUIAxes, 'Z')
            comp.processedRedUIAxes.Visible = 'off';
            comp.processedGreenUIAxes = uiaxes(comp);
            title(comp.processedGreenUIAxes, 'Green Stem Chart [Processed]')
            xlabel(comp.processedGreenUIAxes, 'X')
            ylabel(comp.processedGreenUIAxes, 'Y')
            zlabel(comp.processedGreenUIAxes, 'Z')
            comp.processedGreenUIAxes.Visible = 'off';
            comp.processedBlueUIAxes = uiaxes(comp);
            title(comp.processedBlueUIAxes, 'Blue Stem Chart [Processed]')
            xlabel(comp.processedBlueUIAxes, 'X')
            ylabel(comp.processedBlueUIAxes, 'Y')
            zlabel(comp.processedBlueUIAxes, 'Z')
            comp.processedBlueUIAxes.Visible = 'off';
            comp.FunctionsDropDownLabel = uilabel(comp);
            comp.FunctionsDropDownLabel.Text = 'Functions';
            comp.functionsDropDown = uidropdown(comp);
            comp.functionsDropDown.Items = {'Show Histogram', 'Image Brightening', 'Log Transformation', 'Power Transformation', 'Contrast Stretching', 'Histogram Equalization', 'Histogram Specification'};
            comp.functionsDropDown.Value = 'Show Histogram';
            comp.avaluesliderLabel = uilabel(comp);
            comp.avaluesliderLabel.Text = '''a'' value';
            comp.avalueSlider = uislider(comp);
            comp.avalueSlider.Limits = [0 10];
            comp.avalueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @avalueSliderValueChanged, true);
            comp.avalueSlider.Value = 1;
            comp.bvalueLabel = uilabel(comp);
            comp.bvalueLabel.Text = '''b'' value';
            comp.bvalueSlider = uislider(comp);
            comp.bvalueSlider.Limits = [0 10];
            comp.bvalueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @bvalueSliderValueChanged, true);
            comp.bvalueSlider.Value = 1;
            comp.cvalueSliderLabel = uilabel(comp);
            comp.cvalueSliderLabel.Text = '''c'' value';
            comp.cvalueSlider = uislider(comp);
            comp.cvalueSlider.Limits = [0 10];
            comp.cvalueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @cvalueSliderValueChanged, true);
            comp.cvalueSlider.Value = 1;
            comp.valueSliderLabel = uilabel(comp);
            comp.valueSliderLabel.Text = '''γ'' value';
            comp.valueSlider = uislider(comp);
            comp.valueSlider.Limits = [0 10];
            comp.valueSlider.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @valueSliderValueChanged, true);
            comp.valueSlider.Value = 1;
            comp.creatorLabel = uilabel(comp);
            comp.creatorLabel.Text = 'by 13519029 - Nicholas Chen & 13519135 - Naufal Alexander Suryasumirat';
            comp.cValLabel = uilabel(comp);
            comp.cValLabel.Text = '1';
            comp.gammaValLabel = uilabel(comp);
            comp.gammaValLabel.Text = '1';
            comp.bValLabel = uilabel(comp);
            comp.bValLabel.Text = '1';
            comp.aValLabel = uilabel(comp);
            comp.aValLabel.Text = '1';
            comp.pickBaseButton = uibutton(comp, 'push');
            comp.pickBaseButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @pickBaseButtonPushed, true);
            comp.pickBaseButton.Text = 'Pick Base Image';
            comp.pickSpecificationButton = uibutton(comp, 'push');
            comp.pickSpecificationButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @pickSpecificationButtonPushed, true);
            comp.pickSpecificationButton.Text = 'Pick Spec Image';
            comp.processButton = uibutton(comp, 'push');
            comp.processButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @processButtonPushed, true);
            comp.processButton.Text = 'Process';
            comp.baseImg = uiimage(comp);
            comp.baseImg.Visible = 'off';
            comp.baseImageLabel = uilabel(comp);
            comp.baseImageLabel.Text = 'Base Image';
            comp.specImg = uiimage(comp);
            comp.specImg.Visible = 'off';
            comp.specImageLabel = uilabel(comp);
            comp.specImageLabel.Text = 'Spec Image';
            comp.processedImg = uiimage(comp);
            comp.processedImg.Visible = 'off';
            comp.processedImageLabel = uilabel(comp);
            comp.processedImageLabel.Text = 'Processed Image';
            comp.Label = uilabel(comp);
            comp.debugLabel = uilabel(comp);
            comp.debugLabel.Text = 'debugLabel';
        end
    end
end